classdef dataVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        Panel                 matlab.ui.container.Panel
        PolyOrderSlider       matlab.ui.control.Slider
        PolyOrderSliderLabel  matlab.ui.control.Label
        WindowSlider          matlab.ui.control.Slider
        WindowSliderLabel     matlab.ui.control.Label
        LoadButton            matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        StartTestButton       matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        port = "COM5"; % Description
        baudrate = 115200 % Description
        data = [] % Description
        numPoints = 24; % Description
        window = 21; % Description
        polyorder = 1;
    end
    
    methods (Access = private)
        
        function updateRawDataPlot(app)
            
            if isempty(app.data)
                return;
            end
        
            % Clear previous plot
            cla(app.UIAxes);
            

            freq  = app.data(:,2);
            Zr    = abs(app.data(:,6));
            Zi    = abs(app.data(:,7));  % Invert sign if CSV stored +Im(Z)

            % Sort by frequency
            [freq, sortIdx] = sort(freq);
            Zr = Zr(sortIdx);
            Zi = Zi(sortIdx);

            % Plot each column
            hold(app.UIAxes, 'on');
            plot(app.UIAxes,Zr, Zi, '.', 'DisplayName','Actual Data','MarkerSize',10);
            hold(app.UIAxes, 'off');
    
            title(app.UIAxes, 'Nyquist Plot - Raw Data');
            xlabel(app.UIAxes, 'Z_{real} [\Omega]');
            ylabel(app.UIAxes, '-Z_{imag} [\Omega]');
            %legend(app.UIAxes, 'show');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes,'auto')

        end


        function updateFilteredDataPlot(app)
            if isempty(app.data)
                    return;
            end
        
            % Clear previous plot
            cla(app.UIAxes2);

            freq  = app.data(:,2);
            Zr    = abs(app.data(:,6));
            Zi    = abs(app.data(:,7));  % Invert sign if CSV stored +Im(Z)

            % Sort by frequency
            [freq, sortIdx] = sort(freq);
            Zr = Zr(sortIdx);
            Zi = Zi(sortIdx);

            % Smoothing
            Z_real_sg = sgolayfilt(Zr, app.polyorder, app.window);
            Z_imag_sg = sgolayfilt(Zi, app.polyorder, app.window);

            Zr_smooth = Z_real_sg;
            Zi_smooth = Z_imag_sg;

            freqExt = freq;
            realZ = Zr_smooth;
            imagZ = Zi_smooth;
            
            Zexp = Zr_smooth + 1j * Zi_smooth;

            p1 = polyfit(Zr_smooth, Zi_smooth, 2);
            Zi_fit = polyval(p1, Zr_smooth);


            % Plot each column
            plot(app.UIAxes2,Zr_smooth, Zi_smooth, '.', 'DisplayName','Smoothed Data','MarkerSize',10); 
            hold(app.UIAxes2, 'off');
    
            title(app.UIAxes2, 'Nyquist Plot - Filtered Data');
            xlabel(app.UIAxes2, 'Z_{real} [\Omega]');
            ylabel(app.UIAxes2, '-Z_{imag} [\Omega]');
            %legend(app.UIAxes, 'show');
            grid(app.UIAxes2, 'on');
            xlim(app.UIAxes2,'auto')


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %app.DropDown.Position(3) = min(app.DropDown.Position(3), 150); % Width = 150
            clear all 
            clc 


        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            app.StartTestButton.Text = "Test Running"; 

            serialPort = serialport(app.port,app.baudrate);
            serialPort.Timeout = 600; 
            row = 1; 

            while(row < app.numPoints)
                serialPortData = readline(serialPort);
                values = str2double(split(serialPortData, ','));
            
                if numel(values) == 8 && all(~isnan(values))
                    app.data(row, :) = values';
                    row = row + 1; 
                    reversedData = flipud(app.data);
                    app.UITable.Data = reversedData;
                    app.updateRawDataPlot();
                end
            end 
        
            app.StartTestButton.Text = "Start Test "; 
            reversedData = flipud(app.data);
            app.UITable.Data = reversedData;
            app.WindowSlider.Value = app.window;
            app.PolyOrderSlider.Value = app.polyorder; 
            app.updateRawDataPlot();
            app.updateFilteredDataPlot();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Select CSV File');

            if isequal(file, 0)
                    % User canceled
                    return;
            end
                
            % Full file path
            fullFileName = fullfile(path, file);


            try
                csvData = readmatrix(fullFileName); 
                
                % Extract columns 2 to end
                shiftedData = csvData(:, 2:end);
                
                % Store and display
                app.data = shiftedData;
                reversedData = flipud(app.data);
                app.UITable.Data = reversedData;
                app.updateRawDataPlot();
                app.updateFilteredDataPlot(); 
                app.WindowSlider.Value = app.window;
                app.PolyOrderSlider.Value = app.polyorder;

            catch ME
                uialert(app.UIFigure, "Failed to load file: " + ME.message, "File Error");
            end


        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            value = app.WindowSlider.Value;
            app.window = value; 
            app.updateFilteredDataPlot(); 
            
        end

        % Value changed function: PolyOrderSlider
        function PolyOrderSliderValueChanged(app, event)
            value = app.PolyOrderSlider.Value;
            app.polyorder = value; 
            app.updateFilteredDataPlot(); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Nyquist Plot - Filtered Data')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Nyquist Plot - Raw Data')
            xlabel(app.UIAxes, 'Z_{real} [\Omega]')
            ylabel(app.UIAxes, '-Z_{imag} [\Omega]')
            zlabel(app.UIAxes, 'Z')
            app.StartTestButton = uibutton(app.GridLayout, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test ';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Index'; 'Frequency (Hz)'; 'DFT Cal'; 'DFT Mag'; 'Rz (Ohms)'; 'Rreal'; 'Rimag'; 'Rphase (rads)'};
            app.UITable.RowName = {};
            app.LoadButton = uibutton(app.GridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Panel = uipanel(app.GridLayout);
            app.WindowSliderLabel = uilabel(app.Panel);
            app.WindowSliderLabel.Text = 'Window';
            app.WindowSlider = uislider(app.Panel);
            app.WindowSlider.Limits = [0 30];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.Step = 1;
            app.PolyOrderSliderLabel = uilabel(app.Panel);
            app.PolyOrderSliderLabel.Text = 'Poly. Order';
            app.PolyOrderSlider = uislider(app.Panel);
            app.PolyOrderSlider.Limits = [0 10];
            app.PolyOrderSlider.ValueChangedFcn = createCallbackFcn(app, @PolyOrderSliderValueChanged, true);
            app.PolyOrderSlider.MinorTicks = [1 3 5 7 9];
            app.PolyOrderSlider.Step = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end